classdef TTControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TextArea                 matlab.ui.control.TextArea
        ResetButton              matlab.ui.control.Button
        StopExperimentButton     matlab.ui.control.Button
        StartanExperimentButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartanExperimentButton
        function StartanExperimentButtonPushed(app, event)
            %initialization
            global forceDataCount;
            forceDataCount = 0;
            global speed;
            speed= 50;

            global waveform;
            global test_x_distance;
            test_x_distance = linspace(4.5,219.5,223);
            test_x_distance= rescale(test_x_distance,0,240);
            waveform = sin(test_x_distance);


            global distance;
            distance = 0;
            % Program
            figure;
            x = linspace(4.5,219.5,223);
            y = sin(x);
            y = rescale(y,0,40);
            n = numel(x);


            for i = 1:n
                plot(x(1:i),y(1:i),'-r');
                xlim([0 223]);
                ylim([0 40]);
                drawnow;
                pause((50/speed) *0.5)
            end

            %testSolution()
            %             expInput();
            %             csvInitialisation(speed,waveform,distance);
            %             exptPrep();
            %             csvFinalisation();
            %             fileStorage();
            %% Start Expt Functions
            %%
            function expInput()

                % dialog box for user input
                prompt = {'Enter waveform desired [eg. 3./t.^2.,sin(t)]:';
                    'Enter the distance to be traversed (cm): ';
                    'Enter numeric value of motor speed (mm/min):'};
                dlgtitle = 'Experiment variables';
                dims = [1 50];
                definput = {'3*t.^2','223','50'}; % default values
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                waveform = answer{1};

                x_distance = answer{2};
                numCheck1 = isnumeric(x_distance);
                while (true)
                    if (x_distance>223.0 || x_distance<0.0 || numCheck1 == false)
                        errordlg("Wrong distance input, Max = 223.0");
                        pause(3)
                        close(app.UIFigure);
                        open(app.UIFigure);

                    else
                        break;
                    end
                end
                speed = answer{3};
                numCheck2 = isnumeric(speed);
                while (true)
                    if (speed>50.0 || speed<=0.0 || numCheck2 == false)
                        errordlg("Wrong speed input, Max speed = 50.0");
                        pause(3)
                        close;
                        close;
                        break;
                    else
                        break;
                    end
                end
            end
            %%
            function fileStorage()

                csvread('arduinoResults.csv',0,1);

                % dialog box for user input
                prompt = {'Enter your name: ';
                    'Confirm the waveform'};
                dlgtitle = 'File Storage';
                dims = [1 50];
                definput = {'Name','3*t^2'}; % default values
                fileStored = inputdlg(prompt,dlgtitle,dims,definput);
                name = fileStored{1};
                waveform = fileStored{2};
            end
            %%
            function exptPrep(speed)
                answer2 = questdlg('Are you sure you want to continue?', ...
                    'Confirmation dialog', ...
                    'Yes','No thank you','No thank you'); % last string in method is the default button
                % Handle response
                switch answer2
                    case 'Yes'
                        msgbox("Initializing (5s)...");
                        pause(5);
                        msgbox("Starting Experiment...");
                        %startExperiment (waveform,x_distance,speed);

                    case 'No'
                        pause(2);
                        msgbox("No experiment today then");
                        close(app.UIFigure);
                        return;
                end
                %%
                function csvInitialisation(speed,waveform,distance)
                    experimentTime  = (50/speed *14);                     
                    timestamp  = linspace(0,experimentTime,experimentTime);
                    metadata = ["Speed","Waveform","Distance"];
                    i = 0; %column position
                    while i<3
                        csvwrite('expResults',metadata(i),i,0);
                        i = i +1;
                    end
                    csvwrite('expResults',speed,0,1);
                    csvwrite('expResults',waveform,1,1);
                    csvwrite('expResults',distance,2,1);

                    i = i+2; % moves headings to fourth row
                    dataHeadings = ["Timestamp","x","y","Fx","Fy"];
                    j = 0;
                    while j < 5
                        csvwrite('expResults',dataHeadings(j),i,j); % sets headings below
                        j = j+1;
                    end

                    pause(experimentTime)
                end
                %%
                function startExperiment()
                    %whole loop for length of x traversal
                    % y traversal code
                    % collect Fy and store as variable
                    % x traversal code
                    % collect Fx and store as variable

                    % add value to array as new member (forceDataCount)
                    % increment force data count by 1 for next iteration

                    %out of loop
                    %pass force data in x and y to the exptResults File
                    %resetCarriage() with traversal count
                    %
                    % Arduino Implementation
                    % //servo driver
                    %   readDigitalPin(2, OUTPUT); // DIR +
                    %   readDigitalPin(3, OUTPUT); // DIR -
                    %   readDigitalPin(4, OUTPUT); // PUL +
                    %
                    %   //default
                    %    y_speed = 0.055;
                    %   maxLength_yStage = 500; //mm
                    %    x_speed  = 30.0; // mm/min
                    %
                    %   //Load Cell
                    %   readDigitalPin(2, OUTPUT); // DATA
                    %   readDigitalPin(3, OUTPUT); // SCK/CLK
                    %    gravity = 10.0;
                    %   DFRobot_HX711 MyScale(A1, A2);
                    %   //calibrate weight
                    %   calibrateLC();
                    %   // variables
                    %   force  = 0; // N
                    %   timeStamped = 0; // seconds
                    %
                    %   writeDigitalPin(2, HIGH); //Allows the motor to move forward (test)
                    %   writeDigitalPin(3, LOW);
                    %
                    %
                    %
                    %
                    % }
                    %
                    % void loop()
                    % {
                    %   // this will move the motor using pulses.
                    %   motion_yAxis(x_speed);
                    %   motion_xAxis(y_speed);
                    %
                    %
                    % }
                    %
                    %
                    %
                    % void motion_xAxis(x_speed) {
                    %   if (x_speed == 0 || x_speed > 35) {
                    %     writeDigitalPin(4, LOW);
                    %   }
                    %   else {
                    %     // length in mm
                    %     protectedLength = round(2400 - (2400 / 14.3)); //  time taken to traverse tank at max speed (14.3s)
                    %
                    %     traversalCount = round((speed / 35) * protectedLength)); // max speed is 35 mm/min
                    %
                    %     delayed = round(60 * x_speed / 35);
                    %     if (traversalCount <= protectedLength) {
                    %     // step motor
                    %       writeDigitalPin(4, LOW);
                    %       writeDigitalPin(4, HIGH);
                    %       delmayMicroseconds(delayed);
                    %       traversalCount++;
                    %       force = MyScale.readWeight() * gravity
                    %       timeSec++;//
                    %       delay(1000 - delayed);
                    %
                    %
                    %     }
                    %     else {
                    %--
                    %       writeDigitalPin(4, LOW);
                    %       writeDigitalPin(3, HIGH); //Allows the motor to move in reverse
                    %       writeDigitalPin(2, LOW);
                    %
                    %       if (traversalCount != 0) {
                    %         while (traversalCount > 0) {
                    %           writeDigitalPin(4, LOW);
                    %           writeDigitalPin(4, HIGH);
                    %           delay(delayed);
                    %           traversalCount--;
                    %         }
                    %       }
                    %     }
                    %   }
                    %
                    %   void calibrateLC() {
                    %     // oins A1 and A2 are initialised as scale
                    %     // Set the calibration
                    %     MyScale.setCalibration(1992);
                    %
                    %   }
                    %   void determineForce() {
                    %      gravity = 10;
                    %     Serial.pr(MyScale.readWeight()*gravity, 1);
                    %     Serial.prln(" N");
                    %     delay(delayed);
                    %   }
                end
                %%
                function resetCarriage()
                    %       writeDigitalPin(4, LOW);
                    %       writeDigitalPin(3, HIGH); //Allows the motor to move in reverse
                    %       writeDigitalPin(2, LOW);
                    %
                    %       if (traversalCount != 0) {
                    %         while (traversalCount > 0) {
                    %           writeDigitalPin(4, LOW);
                    %           writeDigitalPin(4, HIGH);
                    %           delay(delayed);
                    %           traversalCount--;
                    %         }
                    %       }
                end

                function testSolution(speed)
                    figure;
                    x = linspace(4.5,219.5,223);
                    y = sin(x);
                    y = rescale(y,0,40);
                    n = numel(x);


                    for i = 1:n
                        plot(x(1:i),y(1:i),'-r');
                        xlim([0 223]);
                        ylim([0 40]);
                        drawnow;
                        pause((50/speed) *0.5)
                    end

                end

            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            n = 0;
            while n <10
                n = n+1;
            end

            % resetCarriage();

            answer2 = questdlg('Carriage reset. Select OK to exit', ...
                'Reset Confirmation dialog', ...
                'Yes','Yes');
        end

        % Button pushed function: StopExperimentButton
        function StopExperimentButtonPushed(app, event)
            answer2 = questdlg('Experiment Stopped', ...
                'Confirmation dialog', ...
                'Resume Experiment','End Experiment','End Experiment'); % last string in method is the default button
            % Handle response
            switch answer2
                case 'Resume Experiment'
                    %StartExperimentButtonPushed(app,event)
                    disp([answer2 ' Experiment ended'])

                case 'No'
                    disp([answer2 'Experiment Ended'])
                    return;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartanExperimentButton = uibutton(app.UIFigure, 'push');
            app.StartanExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StartanExperimentButtonPushed, true);
            app.StartanExperimentButton.Text = 'Start an Experiment';
            app.StopExperimentButton = uibutton(app.UIFigure, 'push');
            app.StopExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StopExperimentButtonPushed, true);
            app.StopExperimentButton.Text = 'Stop Experiment';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'TOWING TANK CONTROL INTERFACE'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TTControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end